classdef databasecreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatabaseCreatorUIFigure         matlab.ui.Figure
        SavetoFileButton                matlab.ui.control.Button
        BatteryAttributesPanel          matlab.ui.container.Panel
        BatteryMasskgEditField          matlab.ui.control.NumericEditField
        BatteryMasskgEditFieldLabel     matlab.ui.control.Label
        CapacitymAhEditField            matlab.ui.control.NumericEditField
        CapacitymAhEditFieldLabel       matlab.ui.control.Label
        PleaseprovidetherelevantdataLabel  matlab.ui.control.Label
        NewRobotDatabaseLabel           matlab.ui.control.Label
        MechanicalCharacteristicsPanel  matlab.ui.container.Panel
        StiffnessNmEditField            matlab.ui.control.NumericEditField
        StiffnessNmEditFieldLabel       matlab.ui.control.Label
        DampeningNsmEditField           matlab.ui.control.NumericEditField
        DampeningNsmLabel               matlab.ui.control.Label
        MotorAttributesPanel            matlab.ui.container.Panel
        MotorPowerWEditField            matlab.ui.control.NumericEditField
        MotorPowerWLabel                matlab.ui.control.Label
        NoloadSpeedrpmEditField         matlab.ui.control.NumericEditField
        NoloadSpeedrpmEditFieldLabel    matlab.ui.control.Label
        StallTorqueNmEditField          matlab.ui.control.NumericEditField
        StallTorqueNmEditFieldLabel     matlab.ui.control.Label
        MotorMasskgEditField            matlab.ui.control.NumericEditField
        MotorMasskgEditFieldLabel       matlab.ui.control.Label
        PhysicalCharacteristicsPanel    matlab.ui.container.Panel
        SingleLegMasskgEditFieldLabel   matlab.ui.control.Label
        RuntimesEditField               matlab.ui.control.NumericEditField
        RuntimesEditFieldLabel          matlab.ui.control.Label
        StrideFrequency1sEditField      matlab.ui.control.NumericEditField
        StrideFrequency1sEditFieldLabel  matlab.ui.control.Label
        SensorArrayMasskgEditField      matlab.ui.control.NumericEditField
        SensorArrayMasskgEditFieldLabel  matlab.ui.control.Label
        SingleLegMasskgEditField        matlab.ui.control.NumericEditField
        TotalMasskgEditField            matlab.ui.control.NumericEditField
        TotalMasskgEditFieldLabel       matlab.ui.control.Label
        LegAttributesPanel              matlab.ui.container.Panel
        LegLengthcmEditFieldLabel       matlab.ui.control.Label
        LegThicknesscmEditField         matlab.ui.control.NumericEditField
        LegThicknesscmEditFieldLabel    matlab.ui.control.Label
        LegWidthcmEditField             matlab.ui.control.NumericEditField
        LegLengthcmEditField            matlab.ui.control.NumericEditField
        LegWidthcmEditFieldLabel        matlab.ui.control.Label
        BodyAttributesPanel             matlab.ui.container.Panel
        BodyHeightcmEditField           matlab.ui.control.NumericEditField
        BodyHeightcmEditFieldLabel      matlab.ui.control.Label
        BodyWidthcmEditField            matlab.ui.control.NumericEditField
        BodyWidthcmEditFieldLabel       matlab.ui.control.Label
        BodyLengthcmEditField           matlab.ui.control.NumericEditField
        BodyLengthcmEditFieldLabel      matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        SelectFileButton                matlab.ui.control.Button
        SelectFileLabel                 matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        RobotNameEditField              matlab.ui.control.EditField
        RobotNameEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        newrobot
        logdbcreator
    end
    
    properties (Access = private)
        mockuppart3 % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mockuppart3 = caller;

            % Set default values for each field
            app.newrobot.body.length = 1;
            app.newrobot.leg.length = 1;
            app.newrobot.leg.width = 1;
            app.newrobot.leg.thickness = 1;
            app.newrobot.body.height = 1;
            app.newrobot.body.width = 1;
            app.newrobot.description = "Default.";
            app.newrobot.body.totalmass = 1;
            app.newrobot.leg.mass = 1;
            app.newrobot.body.sensormass = 1;
            app.newrobot.motor.mass = 1;
            app.newrobot.battery.capacity = 1;
            app.newrobot.battery.mass = 1;
            app.newrobot.motor.stalltorque = 1;
            app.newrobot.motor.noloadspeed = 1;
            app.newrobot.motor.power = 1;
            app.newrobot.body.stridefrequency = 1;
            app.newrobot.body.runtime = 1;
            app.newrobot.body.dampening = 1;
            app.newrobot.body.stiffness = 1;
            app.newrobot.name = "newrobot";
        end

        % Value changed function: BodyLengthcmEditField
        function BodyLengthcmEditFieldValueChanged(app, event)
            app.newrobot.body.length = app.BodyLengthcmEditField.Value;
            
        end

        % Value changed function: LegThicknesscmEditField
        function LegThicknesscmEditFieldValueChanged(app, event)
            app.newrobot.leg.thickness = app.LegThicknesscmEditField.Value;
            
        end

        % Value changed function: LegWidthcmEditField
        function LegWidthcmEditFieldValueChanged(app, event)
            app.newrobot.leg.width = app.LegWidthcmEditField.Value;
            
        end

        % Value changed function: LegLengthcmEditField
        function LegLengthcmEditFieldValueChanged(app, event)
            app.newrobot.leg.length = app.LegLengthcmEditField.Value;
            
        end

        % Value changed function: BodyHeightcmEditField
        function BodyHeightcmEditFieldValueChanged(app, event)
            app.newrobot.body.height = app.BodyHeightcmEditField.Value;
            
        end

        % Value changed function: BodyWidthcmEditField
        function BodyWidthcmEditFieldValueChanged(app, event)
            app.newrobot.body.width = app.BodyWidthcmEditField.Value;
            
        end

        % Value changed function: RobotNameEditField
        function RobotNameEditFieldValueChanged(app, event)
            app.newrobot.name = app.RobotNameEditField.Value;
            
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Bunch of stuff to save a file
            newrobotfilename = sprintf("%s.mat",app.newrobot.name);
            robot = app.newrobot;
            [filename, filepath] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Robot', newrobotfilename);
            if ischar(filename)
                save(fullfile(filepath, filename), 'robot');
            end

            % Update the log
            app.logdbcreator = sprintf("(%s) New database '%s' saved to '%s.mat'.\n",datetime,app.newrobot.name,app.newrobot.name);
            disp(app.logdbcreator)
            externallogupdater(app.mockuppart3,app.logdbcreator);
        end

        % Close request function: DatabaseCreatorUIFigure
        function DatabaseCreatorUIFigureCloseRequest(app, event)
            % Enable the new database button again
            app.mockuppart3.NewDatabaseButton.Enable = "on";

            % Delete the new database builder
            delete(app)
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % Get the image file and store it in our struct
            imgfilename = uigetfile({'*.jpg;*.png;*.gif','Image Files (*.jpg,*.png,*.gif)'},'Select image file...');
            app.newrobot.image = imread(imgfilename);
            app.Image.ImageSource = app.newrobot.image;
        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            app.newrobot.description = app.DescriptionTextArea.Value;
            
        end

        % Value changed function: TotalMasskgEditField
        function TotalMasskgEditFieldValueChanged(app, event)
            app.newrobot.body.totalmass = app.TotalMasskgEditField.Value;
            
        end

        % Value changed function: SingleLegMasskgEditField
        function SingleLegMasskgEditFieldValueChanged(app, event)
            app.newrobot.leg.mass = app.SingleLegMasskgEditField.Value;
            
        end

        % Value changed function: SensorArrayMasskgEditField
        function SensorArrayMasskgEditFieldValueChanged(app, event)
            app.newrobot.body.sensormass = app.SensorArrayMasskgEditField.Value;
            
        end

        % Value changed function: MotorMasskgEditField
        function MotorMasskgEditFieldValueChanged(app, event)
            app.newrobot.motor.mass = app.MotorMasskgEditField.Value;
            
        end

        % Value changed function: CapacitymAhEditField
        function CapacitymAhEditFieldValueChanged(app, event)
            app.newrobot.battery.capacity = app.CapacitymAhEditField.Value;
            
        end

        % Value changed function: BatteryMasskgEditField
        function BatteryMasskgEditFieldValueChanged(app, event)
            app.newrobot.battery.mass = app.BatteryMasskgEditField.Value;
            
        end

        % Value changed function: StallTorqueNmEditField
        function StallTorqueNmEditFieldValueChanged(app, event)
            app.newrobot.motor.stalltorque = app.StallTorqueNmEditField.Value;
            
        end

        % Value changed function: NoloadSpeedrpmEditField
        function NoloadSpeedrpmEditFieldValueChanged(app, event)
            app.newrobot.motor.noloadspeed = app.NoloadSpeedrpmEditField.Value;
            
        end

        % Value changed function: StrideFrequency1sEditField
        function StrideFrequency1sEditFieldValueChanged(app, event)
            app.newrobot.body.stridefrequency = app.StrideFrequency1sEditField.Value;
            
        end

        % Value changed function: RuntimesEditField
        function RuntimesEditFieldValueChanged(app, event)
            app.newrobot.body.runtime = app.RuntimesEditField.Value;
            
        end

        % Value changed function: DampeningNsmEditField
        function DampeningNsmEditFieldValueChanged(app, event)
            app.newrobot.body.dampening = app.DampeningNsmEditField.Value;
            
        end

        % Value changed function: StiffnessNmEditField
        function StiffnessNmEditFieldValueChanged(app, event)
            app.newrobot.body.stiffness = app.StiffnessNmEditField.Value;
            
        end

        % Value changed function: MotorPowerWEditField
        function MotorPowerWEditFieldValueChanged(app, event)
            app.newrobot.motor.power = app.MotorPowerWEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatabaseCreatorUIFigure = uifigure('Visible', 'off');
            app.DatabaseCreatorUIFigure.Name = 'Database Creator';
            app.DatabaseCreatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @DatabaseCreatorUIFigureCloseRequest, true);
            app.GeneralPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.GeneralPanel.Title = 'General';
            app.RobotNameEditFieldLabel = uilabel(app.GeneralPanel);
            app.RobotNameEditFieldLabel.Text = 'Robot Name';
            app.RobotNameEditField = uieditfield(app.GeneralPanel, 'text');
            app.RobotNameEditField.ValueChangedFcn = createCallbackFcn(app, @RobotNameEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.GeneralPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.GeneralPanel);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.SelectFileLabel = uilabel(app.GeneralPanel);
            app.SelectFileLabel.Text = 'Image';
            app.SelectFileButton = uibutton(app.GeneralPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.Image = uiimage(app.GeneralPanel);
            app.BodyAttributesPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.BodyAttributesPanel.Title = 'Body Attributes';
            app.BodyLengthcmEditFieldLabel = uilabel(app.BodyAttributesPanel);
            app.BodyLengthcmEditFieldLabel.Text = 'Body Length (cm)';
            app.BodyLengthcmEditField = uieditfield(app.BodyAttributesPanel, 'numeric');
            app.BodyLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @BodyLengthcmEditFieldValueChanged, true);
            app.BodyLengthcmEditField.Value = 1;
            app.BodyWidthcmEditFieldLabel = uilabel(app.BodyAttributesPanel);
            app.BodyWidthcmEditFieldLabel.Text = 'Body Width (cm)';
            app.BodyWidthcmEditField = uieditfield(app.BodyAttributesPanel, 'numeric');
            app.BodyWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @BodyWidthcmEditFieldValueChanged, true);
            app.BodyWidthcmEditField.Value = 1;
            app.BodyHeightcmEditFieldLabel = uilabel(app.BodyAttributesPanel);
            app.BodyHeightcmEditFieldLabel.Text = 'Body Height (cm)';
            app.BodyHeightcmEditField = uieditfield(app.BodyAttributesPanel, 'numeric');
            app.BodyHeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @BodyHeightcmEditFieldValueChanged, true);
            app.BodyHeightcmEditField.Value = 1;
            app.LegAttributesPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.LegAttributesPanel.Title = 'Leg Attributes';
            app.LegWidthcmEditFieldLabel = uilabel(app.LegAttributesPanel);
            app.LegWidthcmEditFieldLabel.Text = 'Leg Width (cm)';
            app.LegLengthcmEditField = uieditfield(app.LegAttributesPanel, 'numeric');
            app.LegLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @LegLengthcmEditFieldValueChanged, true);
            app.LegLengthcmEditField.Value = 1;
            app.LegWidthcmEditField = uieditfield(app.LegAttributesPanel, 'numeric');
            app.LegWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @LegWidthcmEditFieldValueChanged, true);
            app.LegWidthcmEditField.Value = 1;
            app.LegThicknesscmEditFieldLabel = uilabel(app.LegAttributesPanel);
            app.LegThicknesscmEditFieldLabel.Text = 'Leg Thickness (cm)';
            app.LegThicknesscmEditField = uieditfield(app.LegAttributesPanel, 'numeric');
            app.LegThicknesscmEditField.ValueChangedFcn = createCallbackFcn(app, @LegThicknesscmEditFieldValueChanged, true);
            app.LegThicknesscmEditField.Value = 1;
            app.LegLengthcmEditFieldLabel = uilabel(app.LegAttributesPanel);
            app.LegLengthcmEditFieldLabel.Text = 'Leg Length (cm)';
            app.PhysicalCharacteristicsPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.PhysicalCharacteristicsPanel.Title = 'Physical Characteristics';
            app.TotalMasskgEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.TotalMasskgEditFieldLabel.Text = 'Total Mass (kg)';
            app.TotalMasskgEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.TotalMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @TotalMasskgEditFieldValueChanged, true);
            app.TotalMasskgEditField.Value = 1;
            app.SingleLegMasskgEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.SingleLegMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @SingleLegMasskgEditFieldValueChanged, true);
            app.SingleLegMasskgEditField.Value = 1;
            app.SensorArrayMasskgEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.SensorArrayMasskgEditFieldLabel.Text = 'Sensor Array Mass (kg)';
            app.SensorArrayMasskgEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.SensorArrayMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @SensorArrayMasskgEditFieldValueChanged, true);
            app.SensorArrayMasskgEditField.Value = 1;
            app.StrideFrequency1sEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.StrideFrequency1sEditFieldLabel.Text = 'Stride Frequency (1/s)';
            app.StrideFrequency1sEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.StrideFrequency1sEditField.ValueChangedFcn = createCallbackFcn(app, @StrideFrequency1sEditFieldValueChanged, true);
            app.StrideFrequency1sEditField.Value = 1;
            app.RuntimesEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.RuntimesEditFieldLabel.Text = 'Runtime (s)';
            app.RuntimesEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.RuntimesEditField.ValueChangedFcn = createCallbackFcn(app, @RuntimesEditFieldValueChanged, true);
            app.RuntimesEditField.Value = 1;
            app.SingleLegMasskgEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.SingleLegMasskgEditFieldLabel.Text = 'Single Leg Mass (kg)';
            app.MotorAttributesPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.MotorAttributesPanel.Title = 'Motor Attributes';
            app.MotorMasskgEditFieldLabel = uilabel(app.MotorAttributesPanel);
            app.MotorMasskgEditFieldLabel.Text = 'Motor Mass (kg)';
            app.MotorMasskgEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.MotorMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MotorMasskgEditFieldValueChanged, true);
            app.MotorMasskgEditField.Value = 1;
            app.StallTorqueNmEditFieldLabel = uilabel(app.MotorAttributesPanel);
            app.StallTorqueNmEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.StallTorqueNmEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.StallTorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @StallTorqueNmEditFieldValueChanged, true);
            app.StallTorqueNmEditField.Value = 1;
            app.NoloadSpeedrpmEditFieldLabel = uilabel(app.MotorAttributesPanel);
            app.NoloadSpeedrpmEditFieldLabel.Text = 'No-load Speed (rpm)';
            app.NoloadSpeedrpmEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.NoloadSpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @NoloadSpeedrpmEditFieldValueChanged, true);
            app.NoloadSpeedrpmEditField.Value = 1;
            app.MotorPowerWLabel = uilabel(app.MotorAttributesPanel);
            app.MotorPowerWLabel.Text = 'Motor Power (W)';
            app.MotorPowerWEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.MotorPowerWEditField.ValueChangedFcn = createCallbackFcn(app, @MotorPowerWEditFieldValueChanged, true);
            app.MotorPowerWEditField.Value = 1;
            app.MechanicalCharacteristicsPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.MechanicalCharacteristicsPanel.Title = 'Mechanical Characteristics';
            app.DampeningNsmLabel = uilabel(app.MechanicalCharacteristicsPanel);
            app.DampeningNsmLabel.Text = 'Dampening (N*s/m)';
            app.DampeningNsmEditField = uieditfield(app.MechanicalCharacteristicsPanel, 'numeric');
            app.DampeningNsmEditField.ValueChangedFcn = createCallbackFcn(app, @DampeningNsmEditFieldValueChanged, true);
            app.DampeningNsmEditField.Value = 1;
            app.StiffnessNmEditFieldLabel = uilabel(app.MechanicalCharacteristicsPanel);
            app.StiffnessNmEditFieldLabel.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField = uieditfield(app.MechanicalCharacteristicsPanel, 'numeric');
            app.StiffnessNmEditField.ValueChangedFcn = createCallbackFcn(app, @StiffnessNmEditFieldValueChanged, true);
            app.StiffnessNmEditField.Value = 1;
            app.NewRobotDatabaseLabel = uilabel(app.DatabaseCreatorUIFigure);
            app.NewRobotDatabaseLabel.Text = 'New Robot Database';
            app.PleaseprovidetherelevantdataLabel = uilabel(app.DatabaseCreatorUIFigure);
            app.PleaseprovidetherelevantdataLabel.Text = 'Please provide the relevant data.';
            app.BatteryAttributesPanel = uipanel(app.DatabaseCreatorUIFigure);
            app.BatteryAttributesPanel.Title = 'Battery Attributes';
            app.CapacitymAhEditFieldLabel = uilabel(app.BatteryAttributesPanel);
            app.CapacitymAhEditFieldLabel.Text = 'Capacity (mAh)';
            app.CapacitymAhEditField = uieditfield(app.BatteryAttributesPanel, 'numeric');
            app.CapacitymAhEditField.ValueChangedFcn = createCallbackFcn(app, @CapacitymAhEditFieldValueChanged, true);
            app.CapacitymAhEditField.Value = 1;
            app.BatteryMasskgEditFieldLabel = uilabel(app.BatteryAttributesPanel);
            app.BatteryMasskgEditFieldLabel.Text = 'Battery Mass (kg)';
            app.BatteryMasskgEditField = uieditfield(app.BatteryAttributesPanel, 'numeric');
            app.BatteryMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryMasskgEditFieldValueChanged, true);
            app.BatteryMasskgEditField.Value = 1;
            app.SavetoFileButton = uibutton(app.DatabaseCreatorUIFigure, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            % Show the figure after all components are created
            app.DatabaseCreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = databasecreator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatabaseCreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatabaseCreatorUIFigure)
        end
    end
end