classdef mockuppart3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboforgeDemoUIFigure           matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        RecentProjectsMenu              matlab.ui.container.Menu
        CISCORDUOMenu                   matlab.ui.container.Menu
        MEExcavatorMenu                 matlab.ui.container.Menu
        LoadProjectMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        NewDatabaseMenu                 matlab.ui.container.Menu
        LoadDatabaseMenu                matlab.ui.container.Menu
        RecentDatabasesMenu             matlab.ui.container.Menu
        ExportDatabaseMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OperationManualMenu             matlab.ui.container.Menu
        AboutThisToolMenu               matlab.ui.container.Menu
        StartTabGroup                   matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        Version07Label                  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        DefaultProjectETQuad2023Button  matlab.ui.control.Button
        LoadProjectButton               matlab.ui.control.Button
        NewProjectButton                matlab.ui.control.Button
        WelcometotheRoboForgeDemoLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseBrowserTab              matlab.ui.container.Tab
        CreateviewandloadrobotdatabasesLabel  matlab.ui.control.Label
        OverviewPanel                   matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RestoreDefaultsButton           matlab.ui.control.Button
        DetailsPanel                    matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        NextButton                      matlab.ui.control.Button
        SaveSelectionButton             matlab.ui.control.Button
        AvailableDatabasesPanel         matlab.ui.container.Panel
        Tree                            matlab.ui.container.CheckBoxTree
        ExportDatabaseButton            matlab.ui.control.Button
        LoadDatabaseButton              matlab.ui.control.Button
        NewDatabaseButton               matlab.ui.control.Button
        RobotDesignTab                  matlab.ui.container.Tab
        RDClickNextLabel                matlab.ui.control.Label
        RDDescriptorTextLabel           matlab.ui.control.Label
        RDMassBudgetPanel               matlab.ui.container.Panel
        RDPercent_4                     matlab.ui.control.Label
        RDPercent_3                     matlab.ui.control.Label
        RDPercent_2                     matlab.ui.control.Label
        RDPercent_1                     matlab.ui.control.Label
        RDChassisEditField              matlab.ui.control.NumericEditField
        ChassisEditField_2Label         matlab.ui.control.Label
        RDBatteriesEditField            matlab.ui.control.NumericEditField
        BatteriesEditFieldLabel         matlab.ui.control.Label
        RDLegsEditField                 matlab.ui.control.NumericEditField
        LegsEditFieldLabel              matlab.ui.control.Label
        RDMotorsEditField               matlab.ui.control.NumericEditField
        MotorsEditFieldLabel            matlab.ui.control.Label
        ModelingPanel                   matlab.ui.container.Panel
        RDRunModelingFunctionsLabel     matlab.ui.control.Label
        RDSubmitButton_2                matlab.ui.control.Button
        ScalingRulesPanel               matlab.ui.container.Panel
        RDResetButton                   matlab.ui.control.Button
        RDSubmitButton                  matlab.ui.control.Button
        RDScalingFactorEditField        matlab.ui.control.NumericEditField
        ScalingFactorLabel              matlab.ui.control.Label
        RDPreviousButton                matlab.ui.control.Button
        RDNextButton                    matlab.ui.control.Button
        RDSaveSelectionButton           matlab.ui.control.Button
        RDLogPanel                      matlab.ui.container.Panel
        RDExportLogButton               matlab.ui.control.Button
        RDTextArea_2                    matlab.ui.control.TextArea
        SketchViewPanel                 matlab.ui.container.Panel
        RDHoverLabel                    matlab.ui.control.Label
        RDPayloadMasskgEditField        matlab.ui.control.NumericEditField
        TotalBodyMassLabel_2            matlab.ui.control.Label
        RDRuntimesEditField             matlab.ui.control.NumericEditField
        RuntimesLabel                   matlab.ui.control.Label
        RDNoloadSpeedRPMEditField       matlab.ui.control.NumericEditField
        NoloadSpeedRPMEditFieldLabel    matlab.ui.control.Label
        RDStallTorqueNmEditField        matlab.ui.control.NumericEditField
        StallTorqueNmEditFieldLabel     matlab.ui.control.Label
        RDBatteryPowerWEditField        matlab.ui.control.NumericEditField
        MotorPowerWLabel                matlab.ui.control.Label
        RDLegThicknesscmEditField       matlab.ui.control.NumericEditField
        LegThicknesscmEditFieldLabel    matlab.ui.control.Label
        RDLegWidthcmEditField           matlab.ui.control.NumericEditField
        LegWidthcmEditFieldLabel        matlab.ui.control.Label
        RDLegLengthcmEditField          matlab.ui.control.NumericEditField
        LegLengthcmEditFieldLabel       matlab.ui.control.Label
        RDBodyHeightcmEditField         matlab.ui.control.NumericEditField
        BodyHeightcmEditFieldLabel      matlab.ui.control.Label
        RDBodyWidthcmEditField          matlab.ui.control.NumericEditField
        BodyWidthcmEditFieldLabel       matlab.ui.control.Label
        RDBodyLengthcmEditField         matlab.ui.control.NumericEditField
        BodyLengthcmEditFieldLabel      matlab.ui.control.Label
        RDTotalBodyMasskgEditField      matlab.ui.control.NumericEditField
        TotalBodyMassLabel              matlab.ui.control.Label
        RDUIAxes                        matlab.ui.control.UIAxes
        DeliverablesTab                 matlab.ui.container.Tab
        OutputPreviewPanel              matlab.ui.container.Panel
        OutputPreview                   matlab.ui.control.TextArea
        BOMSaveValuesButton             matlab.ui.control.Button
        BatteryPanel                    matlab.ui.container.Panel
        BOMCopyButton_2                 matlab.ui.control.Image
        BOMLinktoAdequateBatteriesButton  matlab.ui.control.Button
        BoMRibbon                       matlab.ui.container.TabGroup
        BoMBatt1Tab                     matlab.ui.container.Tab
        BOMBatteryVoltageText1          matlab.ui.control.NumericEditField
        BOMBatteryVoltageLabel1         matlab.ui.control.Label
        BOMBatteryWeightText1           matlab.ui.control.NumericEditField
        BOMBatteryWeightLabel1          matlab.ui.control.Label
        BOMBatteryCapText1              matlab.ui.control.NumericEditField
        BOMBatteryCapLabel1             matlab.ui.control.Label
        BOMBatt2Tab                     matlab.ui.container.Tab
        BOMBatteryVoltageText2          matlab.ui.control.NumericEditField
        BOMBatteryVoltageLabel2         matlab.ui.control.Label
        BOMBatteryWeightText2           matlab.ui.control.NumericEditField
        BOMBatteryWeightLabel2          matlab.ui.control.Label
        BOMBatteryCapText2              matlab.ui.control.NumericEditField
        BOMBatteryCapLabel2             matlab.ui.control.Label
        BOMBatt3Tab                     matlab.ui.container.Tab
        BOMBatteryVoltageText3          matlab.ui.control.NumericEditField
        BOMBatteryVoltageLabel3         matlab.ui.control.Label
        BOMBatteryWeightText3           matlab.ui.control.NumericEditField
        BOMBatteryWeightLabel3          matlab.ui.control.Label
        BOMBatteryCapText3              matlab.ui.control.NumericEditField
        BOMBatteryCapLabel3             matlab.ui.control.Label
        BOMBatt4Tab                     matlab.ui.container.Tab
        BOMBatteryVoltageText4          matlab.ui.control.NumericEditField
        BOMBatteryVoltageLabel4         matlab.ui.control.Label
        BOMBatteryWeightText4           matlab.ui.control.NumericEditField
        BOMBatteryWeightLabel4          matlab.ui.control.Label
        BOMBatteryCapText4              matlab.ui.control.NumericEditField
        BOMBatteryCapLabel4             matlab.ui.control.Label
        BOMBatt5Tab                     matlab.ui.container.Tab
        BOMBatteryVoltageText5          matlab.ui.control.NumericEditField
        BOMBatteryVoltageLabel5         matlab.ui.control.Label
        BOMBatteryWeightText5           matlab.ui.control.NumericEditField
        BOMBatteryWeightLabel5          matlab.ui.control.Label
        BOMBatteryCapText5              matlab.ui.control.NumericEditField
        BOMBatteryCapLabel5             matlab.ui.control.Label
        MotorPanel                      matlab.ui.container.Panel
        BOMStallTText                   matlab.ui.control.NumericEditField
        BOMStallTLabel                  matlab.ui.control.Label
        BOMNoLoadText                   matlab.ui.control.NumericEditField
        BOMNoLoadLabel                  matlab.ui.control.Label
        BOMCopyButton_1                 matlab.ui.control.Image
        BOMLinktoAdequateMotorsButton   matlab.ui.control.Button
        BOMMotorMassText                matlab.ui.control.NumericEditField
        BOMMotorMassLabel               matlab.ui.control.Label
        BOMMotorPowerText               matlab.ui.control.NumericEditField
        BOMMotorPowerLabel              matlab.ui.control.Label
        BillofMaterialsLabel            matlab.ui.control.Label
        BOMPrevButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        databasecreator % Dialog app
    end

    properties (Access = public)
        MotorMass
        SimOutputs
        NoloadSpeed
        StallTorque
        MotorPower
        testrobot
        editingrobot
        textlog
        BOMFile
        editingshadow
        scalingshadow
        recentdatabases
        newrecentdatabasemenu
        BatteryCap4cell
        BatteryCap5cell
        BatteryCap6cell
        BatteryCap7cell
        BatteryCap8cell
        BOMText
        totalscale
        totalscalecounter
        robotmeta
    end

    methods (Access = public)

        % Get info on what happened in the DB Creator in real time
        function externallogupdater(app,externallog)
            app.textlog = append(app.textlog,externallog);
            app.RDTextArea_2.Value = app.textlog;
        end

        % Handle all the graphing in the Robot Design tab
        function robotgrapher(app,robotinfo)
            % Clear whatever was already in the plot
            cla(app.RDUIAxes);

            % Plot the robot
            % Robot body
            body = rectangle(app.RDUIAxes,'Position',[0, robotinfo.leg.length-0.5*robotinfo.body.height, robotinfo.body.length, robotinfo.body.height],'FaceColor','b','Curvature',1);
            % Robot legs
            foreleg = line(app.RDUIAxes,[0.12*robotinfo.body.length,0.12*robotinfo.body.length], [0,robotinfo.leg.length],'linewidth',5,'color','#404040');
            rearleg = line(app.RDUIAxes,[robotinfo.body.length-0.1*robotinfo.body.length robotinfo.body.length-0.1*robotinfo.body.length ...
                ], [0,robotinfo.leg.length],'linewidth',5,'color','#404040');

            % Update the side values
            app.RDLegThicknesscmEditField.Value = robotinfo.leg.thickness;
            app.RDLegWidthcmEditField.Value = robotinfo.leg.width;
            app.RDLegLengthcmEditField.Value = robotinfo.leg.length;
            app.RDBodyHeightcmEditField.Value = robotinfo.body.height;
            app.RDBodyLengthcmEditField.Value = robotinfo.body.length;
            app.RDBodyWidthcmEditField.Value = robotinfo.body.width;
            app.RDTotalBodyMasskgEditField.Value = robotinfo.body.totalmass;
            app.RDStallTorqueNmEditField.Value = robotinfo.motor.stalltorque;
            app.RDNoloadSpeedRPMEditField.Value = robotinfo.motor.noloadspeed;
            app.RDRuntimesEditField.Value = robotinfo.body.runtime;
            app.RDPayloadMasskgEditField.Value = robotinfo.body.sensormass;


            %Update Mass Budget values
            app.RDMotorsEditField.Value = (robotinfo.motor.mass/robotinfo.body.totalmass)*100;
            app.RDLegsEditField.Value = (robotinfo.leg.mass*4/robotinfo.body.totalmass)*100;
            app.RDBatteriesEditField.Value = (robotinfo.battery.mass*2/robotinfo.body.totalmass)*100;
            app.RDChassisEditField.Value = ((robotinfo.body.totalmass - ...
                (robotinfo.motor.mass) - (robotinfo.leg.mass*4) - ...
                (robotinfo.battery.mass))/robotinfo.body.totalmass)*100;
        end

        function [newModelParam] = dynamicScale(app,modelParam,a_length)
            % This function dynamically scales the parameters in the struct modelParam
            % according to the length scaling factor a_length

            % Model Parameter structs should contain the following:
            %   Length
            %   Mass
            %   Stiffness
            %   Damping
            %   Frequency
            %   Gravity
            %   Touch Down Angle
            %   Touch Down Heading
            %   Touch Down Velocity

            newModelParam.body.length = modelParam.body.length * a_length;
            newModelParam.body.height = modelParam.body.height * a_length;
            newModelParam.body.width = modelParam.body.width * a_length;
            newModelParam.leg.length = modelParam.leg.length * a_length;
            newModelParam.leg.width = modelParam.leg.width * a_length;
            newModelParam.leg.thickness = modelParam.leg.thickness * a_length;
            newModelParam.body.totalmass = modelParam.body.totalmass * a_length^3;
            newModelParam.leg.mass = modelParam.leg.mass * a_length^3;
            newModelParam.motor.mass = modelParam.motor.mass * a_length^3;
            newModelParam.body.sensormass = modelParam.body.sensormass * a_length^3;
            tau0 = 0.145; % Continuous torque constant. For a specific motor series for now
            alpha0 = 0.0083; % Ratio of continuous to stall torque
            k0 = 69.5; % Scalable motor torque constant
            newModelParam.motor.stalltorque = (newModelParam.motor.mass/4)^2*tau0/alpha0;
            newModelParam.motor.noloadspeed = k0*tau0/alpha0;
            newModelParam.battery.mass = modelParam.battery.mass * a_length^3;

            % Spit back out duplicates of the other fields for now
            % because we're lazy
            newModelParam.name = modelParam.name;
            newModelParam.description = modelParam.description;
            %                 newModelParam.motor.power = modelParam.motor.power * a_length^(7/2);
            %                 newModelParam.motor.stalltorque = modelParam.motor.stalltorque;
            %                 newModelParam.motor.noloadspeed = modelParam.motor.noloadspeed;
            %                 newModelParam.battery.capacity = modelParam.battery.capacity;
            %newModelParam.battery.mass = modelParam.battery.mass;
            
            newModelParam.body.stridefrequency = modelParam.body.stridefrequency;
            newModelParam.body.runtime = modelParam.body.runtime;
            newModelParam.image = modelParam.image;
            %                 newModelParam.body.dampening = modelParam.body.dampening;
            %                 newModelParam.body.stiffness = modelParam.body.stiffness;

            %                 newModelParam.stiffness = modelParam.stiffness * a_length^2;
            %                 newModelParam.damping = modelParam.damping * a_length^(5/2);
            %                 newModelParam.frequency = modelParam.frequency * a_length^(-1/2);
            %                 newModelParam.gravity = modelParam.gravity;
            %                 newModelParam.tdVel = modelParam.tdVel * a_length^(1/2);
            %                 newModelParam.tdHeading= modelParam.tdHeading;
            %                 newModelParam.tdAngle= modelParam.tdAngle;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clear out variables in the base workspace
            evalin("base", "clear");

            % Hide the main tab group and show the start tab group
            app.TabGroup.Visible = "off";
            app.StartTabGroup.Visible = "on";

            % Initialize the text log
            app.textlog = "";

            %Initialize the BOM
            app.BOMFile = "";

            %Initialize motor power and mass for tab switch
            app.MotorPower = 0;
            app.MotorMass = 0;

            % Initialize the scaling factors
            app.totalscale = 1;
            app.totalscalecounter = 0;

            % Disable the reset button initially
            app.RDResetButton.Enable = "off";

            % Initialize recent databases
            app.recentdatabases = ["name", "filepath", "checksum"];
            recentdatabases = app.recentdatabases;

            % Initially hide the Recent Databases menu
            app.RecentDatabasesMenu.Visible = "off";

            % Check to see if the settings file is made
            % If not, make it
            if ~isfile("roboforgesettings.mat")
                % Make the recent databases array
                save("roboforgesettings.mat", "recentdatabases");
                % Eventually, make the recent projects array

                % Once done, load it up
                load("roboforgesettings.mat", "recentdatabases");
            else
                % Otherwise, just load it up
                load("roboforgesettings.mat");
                % If there is more than one row in recentdatabases, add the
                % file to the Recent Databases menu
                if (height(recentdatabases) > 1)
                    % Make the Recent Databases menu appear
                    app.RecentDatabasesMenu.Visible = "on";
                    % Make new menu entry
                    app.newrecentdatabasemenu = uimenu(app.RecentDatabasesMenu);
                    app.newrecentdatabasemenu.Text = recentdatabases(2,1);
                    % Assign load database callback to our new menu
                    app.newrecentdatabasemenu.MenuSelectedFcn = createCallbackFcn(app, @NewRecentDatabaseMenuSelected, true);
                end
            end

            % New function for loading the recent databases
            function NewRecentDatabaseMenuSelected(app, event)
                % This is literally a copy of the regular load database but
                % just without the stuff we don't need because we already know
                % where this file is.
                % Load the database from the file we already know
                recentloadedrobotfilepath = recentdatabases(2,2); % This is taken from the settings file array

                robot = "";
                load(recentloadedrobotfilepath); % load the database
                app.editingrobot = robot; % Put the database into a temp struct that we work on
                app.editingshadow = robot; % Put the database into a second struct to reset to later
                app.scalingshadow = robot; % Put the database into a third struct to record the scaling state
                % Grab some metadata for later
                app.robotmeta = dir(recentloadedrobotfilepath);


                % Grab the names of all the database fields
                editingdatabasenames = string(fieldnames(app.editingrobot));

                % Build the description
                robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
                robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
                robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
                robotdetailtext4 = sprintf("Date Modified: %s \n\n",app.robotmeta.date);
                robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
                app.TextArea.Value = robotdetail;

                % Read data from the script and put it in a tree
                firstdatabasenode = uitreenode(app.Tree);
                firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry

                % Update the overview picture
                app.Image.ImageSource = app.editingrobot.image;

                % Call the robot plotting function
                robotgrapher(app,app.editingrobot);

                % Swap over to the database browser
                app.TabGroup.SelectedTab = app.DatabaseBrowserTab;

                % Update the log
                % Specify that this one is the default one
                logrecentdataload = sprintf("(%s) Recent database loaded from '%s'.\n",datetime,recentloadedrobotfilepath);
                app.textlog = append(app.textlog,logrecentdataload);
                app.RDTextArea_2.Value = app.textlog;
                scroll(app.RDTextArea_2,'bottom');
            end
        end

        % Button pushed function: RDSubmitButton_2
        function SubmitandRunButtonPushed(app, event)
            % This is used to run the old DR4 models
            % Create a loading window and display it while this runs
            loadingfig = uifigure("Name","Loading...","WindowStyle","modal");
            loadingfig.Position(3:4) = [500 200];
            simprog = uiprogressdlg(loadingfig,'Title','Robot Architecture','Message','Compiling Physical Characteristics');
            drawnow;

            % Update the log for the start
            log6 = sprintf("(%s) Simulink analysis initialized.\n",datetime);
            app.textlog = append(app.textlog,log6);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');

            % Take values from the currently loaded model and send it to Simulink
            simprog.Value = 0.33;
            % Probably will delete this chunk later, now that old robot
            % design tab is deprecated
            %             % If a database has been loaded, use its value. Otherwise, use
            %             % the Motor mass field in the old tab
            %             if ~(app.RDTotalBodyMasskgEditField.Value)
            %                 app.MotorMass = app.MotorMassEditField.Value;
            %             else
            %                 app.MotorMass = app.RDTotalBodyMasskgEditField.Value;
            %             end
            legLengthMeters = app.editingrobot.leg.length/100;
            assignin("base","m", app.editingrobot.body.totalmass); % Export Total Mass
            assignin("base","L", legLengthMeters); % Export Leg Length
            assignin("base","f_stride", app.editingrobot.body.stridefrequency);
            assignin("base","t", app.editingrobot.body.runtime);
            pause(1);

            simprog.Value = 0.66;
            simprog.Message = 'Running System Composer';
            app.SimOutputs = sim("robotModel.slx");

            simprog.Value = 1;
            simprog.Message = 'Returning Results';
            pause(1);
            app.NoloadSpeed = app.SimOutputs.noloadspeed.data;
            app.StallTorque = app.SimOutputs.stalltorque.data;
            app.MotorPower = app.SimOutputs.power.data;
            %             app.NoloadSpeedEditField.Value = app.NoloadSpeed;
            %             app.StallTorqueEditField.Value = app.StallTorque;
            %             app.PowerEditField.Value = app.MotorPower;
            app.RDNoloadSpeedRPMEditField.Value = app.NoloadSpeed;
            app.BOMNoLoadText.Value = app.NoloadSpeed(1);
            app.RDStallTorqueNmEditField.Value = app.StallTorque;
            app.BOMStallTText.Value = app.StallTorque(1);
            app.RDBatteryPowerWEditField.Value = app.MotorPower;

            % Recieve the battery outputs
            app.BatteryCap4cell = app.SimOutputs.amphour4.data*1000;
            app.BatteryCap5cell = app.SimOutputs.amphour5.data*1000;
            app.BatteryCap6cell = app.SimOutputs.amphour6.data*1000;
            app.BatteryCap7cell = app.SimOutputs.amphour7.data*1000;
            app.BatteryCap8cell = app.SimOutputs.amphour8.data*1000;

            app.BOMMotorPowerText.Value = app.MotorPower;
            app.BOMMotorMassText.Value = app.editingrobot.motor.mass;
            app.BOMBatteryCapText1.Value = app.BatteryCap4cell(1);
            app.BOMBatteryCapText2.Value = app.BatteryCap5cell(1);
            app.BOMBatteryCapText3.Value = app.BatteryCap6cell(1);
            app.BOMBatteryCapText4.Value = app.BatteryCap7cell(1);
            app.BOMBatteryCapText5.Value = app.BatteryCap8cell(1);

            % Note: 150 used here as average energy density of maxAmp batt
            app.BOMBatteryWeightText1.Value = ((app.BatteryCap4cell(1)*14.8)/150);
            app.BOMBatteryWeightText2.Value = ((app.BatteryCap5cell(1)*18.5)/150);
            app.BOMBatteryWeightText3.Value = ((app.BatteryCap6cell(1)*22.2)/150);
            app.BOMBatteryWeightText4.Value = ((app.BatteryCap7cell(1)*25.9)/150);
            app.BOMBatteryWeightText5.Value = ((app.BatteryCap8cell(1)*29.6)/150);

            close(loadingfig); % Closes the loading bar

            % Update the log
            log7 = sprintf("(%s) Simulink analysis completed.\n",datetime);
            app.textlog = append(app.textlog,log7);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');

            % Let the user know they can move on to deliverables
            app.RDClickNextLabel.Visible = "on";
            app.RDNextButton.Enable = "on";

            % Flash the updated fields so the user knows
            app.RDNoloadSpeedRPMEditField.BackgroundColor = "#00ffff";
            app.RDStallTorqueNmEditField.BackgroundColor = "#00ffff";
            app.RDBatteryPowerWEditField.BackgroundColor = "#00ffff";
            pause(1);
            app.RDNoloadSpeedRPMEditField.BackgroundColor = "#aaffff";
            app.RDStallTorqueNmEditField.BackgroundColor = "#aaffff";
            app.RDBatteryPowerWEditField.BackgroundColor = "#aaffff";
            pause(0.75);
            app.RDNoloadSpeedRPMEditField.BackgroundColor = "white";
            app.RDStallTorqueNmEditField.BackgroundColor = "white";
            app.RDBatteryPowerWEditField.BackgroundColor = "white";

        end

        % Callback function: LoadDatabaseButton, LoadDatabaseMenu
        function LoadDatabaseMenuSelected(app, event)
            % Load the database from file
            [loadingdatabasefile, loadingdatabasepath] = uigetfile(".mat"); % This gets the filename and path
            % Stop everything if user hits cancel
            if (loadingdatabasefile == 0)
                % Bring the window back into focus
                app.RoboforgeDemoUIFigure.Visible = 'off';
                app.RoboforgeDemoUIFigure.Visible = 'on';
                return
            end
            % Bring the window back into focus
            app.RoboforgeDemoUIFigure.Visible = 'off';
            app.RoboforgeDemoUIFigure.Visible = 'on';
            % Store the full file path
            lastloadedrobotfilepath = append(loadingdatabasepath,loadingdatabasefile);
            load(lastloadedrobotfilepath); % load the database
            app.editingrobot = robot; % Put the database into a temp struct that we work on
            app.editingshadow = robot; % Put the database into a second struct to reset to later
            app.scalingshadow = robot; % Put the database into a third struct to record the scaling state
            % Grab some metadata for later
            app.robotmeta = dir(lastloadedrobotfilepath);


            % Check if we already loaded this before
            tempchecksum = Simulink.getFileChecksum(loadingdatabasefile);
            % Make a temp copy of recent databases
            recentdatabases = app.recentdatabases;
            % Is this file in the Recents array?
            if ~(ismember(tempchecksum,recentdatabases))
                % Sort all this data
                tempdatabaseentry = [string(app.editingrobot.name), string(lastloadedrobotfilepath), string(tempchecksum)];
                % Store this database's path and checksum into Recents
                recentdatabases = vertcat(recentdatabases, tempdatabaseentry);
                % Save this to file
                save("roboforgesettings.mat","recentdatabases");
            end


            % Grab the names of all the database fields
            editingdatabasenames = string(fieldnames(app.editingrobot));

            % Build the description
            robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
            robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
            robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
            robotdetailtext4 = sprintf("Date Modified: %s \n\n",app.robotmeta.date);
            robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
            app.TextArea.Value = robotdetail;

            % Read data from the script and put it in a tree
            firstdatabasenode = uitreenode(app.Tree);
            firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry

            % Update the overview picture
            app.Image.ImageSource = app.editingrobot.image;

            % Call the robot plotting function
            robotgrapher(app,app.editingrobot);

            % Update the log
            log1 = sprintf("(%s) Database '%s' loaded.\n",datetime,loadingdatabasefile);
            app.textlog = append(app.textlog,log1);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');

        end

        % Callback function: NewDatabaseButton, NewDatabaseMenu
        function NewDatabaseButtonPushed(app, event)
            % Disable New Database button while dialog is open
            app.NewDatabaseButton.Enable = "off";

            % Let the user make a new robot database

            % Call the database app
            app.databasecreator = databasecreator(app);
        end

        % Close request function: RoboforgeDemoUIFigure
        function RoboforgeDemoUIFigureCloseRequest(app, event)
            delete(app.databasecreator)
            delete(app)

        end

        % Button pushed function: RDSubmitButton
        function RDSubmitButtonPushed(app, event)
            % Reset the view if the user enters a negative value and stop
            if (app.RDScalingFactorEditField.Value < 0)
                app.RDResetButtonPushed(event);
                negativewarning = uifigure;
                negativewarning.Position(3:4) = [500 200];
                beep;
                uialert(negativewarning,"Negative values are not valid for this field. Resetting robot to its initial state.","Warning","CloseFcn",@(h,e) close(negativewarning));
                return
            end
            
            % Call the scaling function
            app.editingrobot = dynamicScale(app,app.editingrobot,app.RDScalingFactorEditField.Value);

            % Call the plotting function
            robotgrapher(app,app.editingrobot);

            % Enable the reset button if we scaled
            if ~(app.RDScalingFactorEditField.Value == 1)
                app.RDResetButton.Enable = "on";
            end

            % Build the cumulative scaling amount
            app.totalscale = app.totalscale * app.RDScalingFactorEditField.Value;
            app.totalscalecounter = app.totalscalecounter + 1;

            % Update the log
            % Make it a single line if we've only scaled once
            if (app.totalscalecounter == 1)
                log2 = sprintf("(%s) Scaling factor of %0.2g has been applied.\n",datetime,app.RDScalingFactorEditField.Value);
                app.textlog = append(app.textlog,log2);
                app.RDTextArea_2.Value = app.textlog;
                scroll(app.RDTextArea_2,'bottom');

                % Also now show the line indicating that the user can move
                % on to the Deliverables tab
                app.RDClickNextLabel.Visible = "on";
                app.RDNextButton.Enable = "on";

                % Otherwise, make it a combined line
            else
                log2 = sprintf("(%s) Scaling factor of %0.2g has been applied. Cumulative scaling factor is currently %0.5g.\n",datetime,app.RDScalingFactorEditField.Value,app.totalscale);
                app.textlog = append(app.textlog,log2);
                app.RDTextArea_2.Value = app.textlog;
                scroll(app.RDTextArea_2,'bottom');
            end
        end

        % Button pushed function: RDExportLogButton
        function RDExportLogButtonPushed(app, event)
            % Save contents of the log to file (in the same folder)
            writematrix(app.textlog,'robotlog.txt');

            % Update the log
            log3 = sprintf("(%s) Log exported to 'robotlog.txt'.\n",datetime);
            app.textlog = append(app.textlog,log3);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Move to the Robot Design tab
            app.TabGroup.SelectedTab = app.RobotDesignTab;
        end

        % Button pushed function: RDPreviousButton
        function RDPreviousButtonPushed(app, event)
            % Go back to the Database Browser tab
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

        end

        % Button pushed function: RDResetButton
        function RDResetButtonPushed(app, event)
            % Reset the values of the editing database to the loaded one
            app.editingrobot = app.editingshadow;
            app.RDScalingFactorEditField.Value = 1;

            % Reset the cumulative scaling
            app.totalscale = 1;
            app.totalscalecounter = 0;

            % Disable the button
            app.RDResetButton.Enable = "off";
            app.RDNextButton.Enable = "off";
            app.RDClickNextLabel.Visible = "off";

            % Call the plotting function
            robotgrapher(app,app.editingrobot);

            % Update the log
            log5 = sprintf("(%s) Robot scaling restored to its initial state.\n",datetime);
            app.textlog = append(app.textlog,log5);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end

        % Button pushed function: NewProjectButton
        function NewProjectButtonPushed(app, event)
            app.StartTabGroup.Visible = "off";
            app.TabGroup.Visible = "on";
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;

            % Update the log
            log0 = sprintf("(%s) New Project Started.\n",datetime);
            app.textlog = append(app.textlog,log0);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end

        % Button pushed function: RDNextButton
        function RDNextButtonPushed(app, event)
            % Move to the Deliverables tab
            app.BOMMotorPowerText.Value = app.MotorPower;
            app.BOMMotorMassText.Value = app.editingrobot.motor.mass;
            app.BOMNoLoadText.Value = app.NoloadSpeed;
            app.BOMStallTText.Value = app.StallTorque;

            BOMTextMotorPower = app.BOMMotorPowerText.Value;
            BOMTextMotorMass = app.BOMMotorMassText.Value;
            BOMTextNoLoad = app.BOMNoLoadText.Value;
            BOMTextStallT = app.BOMStallTText.Value;

            BOMTextBatteryCap4 = app.BOMBatteryCapText1.Value;
            BOMTextBatteryWeight4 = (app.BOMBatteryCapText1.Value*(3.7*4))/150;
            BOMTextBatteryVoltage4 = 3.7*4;

            BOMTextBatteryCap5 = app.BOMBatteryCapText2.Value;
            BOMTextBatteryWeight5 = (app.BOMBatteryCapText2.Value*(3.7*5))/150;
            BOMTextBatteryVoltage5 = 3.7*5;

            BOMTextBatteryCap6 = app.BOMBatteryCapText3.Value;
            BOMTextBatteryWeight6 = (app.BOMBatteryCapText3.Value*(3.7*6))/150;
            BOMTextBatteryVoltage6 = 3.7*6;

            BOMTextBatteryCap7 = app.BOMBatteryCapText4.Value;
            BOMTextBatteryWeight7 = (app.BOMBatteryCapText4.Value*(3.7*7))/150;
            BOMTextBatteryVoltage7 = 3.7*7;

            BOMTextBatteryCap8 = app.BOMBatteryCapText5.Value;
            BOMTextBatteryWeight8 = (app.BOMBatteryCapText5.Value*(3.7*8))/150;
            BOMTextBatteryVoltage8 = 3.7*8;

            app.BOMText = sprintf("Motor Power (W): %.2f \n" + ...
                "Total Motor Mass (g): %.2f \n" + ...
                "No Load Speed (RPM): %.2f \n" + ...
                "Stall Torque (Nm): %.2f\n" + ...
                "***************************\n" + ...
                "4 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "5 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "6 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "7 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "8 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n", BOMTextMotorPower,BOMTextMotorMass,BOMTextNoLoad,BOMTextStallT,BOMTextBatteryCap4,BOMTextBatteryWeight4,...
                BOMTextBatteryVoltage4,BOMTextBatteryCap5,BOMTextBatteryWeight5,BOMTextBatteryVoltage5,BOMTextBatteryCap6,...
                BOMTextBatteryWeight6,BOMTextBatteryVoltage6,BOMTextBatteryCap7,BOMTextBatteryWeight7,BOMTextBatteryVoltage7,...
                BOMTextBatteryCap8,BOMTextBatteryWeight8,BOMTextBatteryVoltage8);
            app.OutputPreview.Value = app.BOMText;
            app.TabGroup.SelectedTab = app.DeliverablesTab;
        end

        % Image clicked function: BOMCopyButton_1
        function BOMCopyButton_1ImageClicked(app, event)
            motorWatts = app.BOMMotorPowerText.Value;
            motorRPM = app.BOMMotorMassText.Value;
            motorTorque = 500;
            %motorURLVar = 'https://www.maxongroup.us/maxon/view/service_search?query=Motor+%d+W+Motor+%d+rpm+Motor+%d+mNm'

            %motorString = sprintf('https://www.maxongroup.us/maxon/view/service_search?queryMotor+%d+W+Motor+%d+rpm+Motor+%d+mNm', motorWatts, motorRPM, motorTorque);
            motorString = sprintf('https://www.maxongroup.us/maxon/view/service_search?query=*&filterKategorie1=Motor&channel=factfinderProducts_ws_maxon_us__en&followSearch=10000&format=JSON');
            %motorString = 'https://www.maxongroup.us/maxon/view/service_search?query=*&filterKategorie1=Motor&channel=factfinderProducts_ws_maxon_us__en&followSearch=10000&format=JSON';
            clipboard('copy', motorString);
        end

        % Image clicked function: BOMCopyButton_2
        function BOMCopyButton_2ImageClicked(app, event)
            if(app.BoMRibbon.SelectedTab == app.BoMBatt1Tab)
                battMAH = app.BOMBatteryCapText1.Value;
                battVolts = 14.8;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt2Tab)
                battMAH = app.BOMBatteryCapText2.Value;
                battVolts = 18.5;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt3Tab)
                battMAH = app.BOMBatteryCapText3.Value;
                battVolts = 22.2;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt4Tab)
                battMAH = app.BOMBatteryCapText4.Value;
                battVolts = 25.9;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt5Tab)
                battMAH = app.BOMBatteryCapText5.Value;
                battVolts = 29.6;
            end

            maxAmpLiPomAh = [430 670 860 1050 1300 1450 1850 2000 2250 2375 2800 3250 ...
                3750 4000 4500 4750 5200 5450 6000 6500 7500 8000 9000 10400 10900 ...
                11000 12000 16000 17000 22000 23000 31000 34000 46000 62000];
            maxAmpsIterate = 1;
            %maxampsURLVar = 'https://maxamps.com/collections/lipo-%dmah-packs?pf_rs7_voltage=%.2f%%3A59.20';

            if (battMAH > 62000)
                searchLB = 62000;
            else
                while (battMAH > maxAmpLiPomAh(maxAmpsIterate))
                    maxAmpsIterate = maxAmpsIterate + 1;
                end
                searchLB = maxAmpLiPomAh(maxAmpsIterate);
            end

            if (searchLB == 670)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 1050)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 1300)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 1450)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 3750)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 5200)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 10400)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            else
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%dmah-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            end
            maxAmpURL = sprintf(maxAmpsURLVar, searchLB, battVolts, battVolts);
            %system(sprintf('explorer https://maxamps.com/collections/lipo-%dmah-packs', searchLB));
            clipboard('copy', maxAmpURL);
        end

        % Button pushed function: BOMLinktoAdequateMotorsButton
        function BOMLinktoAdequateMotorsButtonPushed(app, event)
            %             motorWatts = app.BOMMotorPowerText.Value;
            %             motorRPM = app.BOMMotorMassText.Value;
            %             motorTorque = 500;
            %             maxonLinkValue = 10000;
            %motorURLVar = 'https://www.maxongroup.us/maxon/view/service_search?query=Motor+%d+W+Motor+%d+rpm+Motor+%d+mNm'

            %motorString = sprintf('https://www.maxongroup.us/maxon/view/service_search?queryMotor+%d+W+Motor+%d+rpm+Motor+%d+mNm', motorWatts, motorRPM, motorTorque);
            %motorString = "https://www.maxongroup.us/maxon/view/service_search?query=*&filterKategorie1=Motor&channel=factfinderProducts_ws_maxon_us__en&followSearch=10000&format=JSON";
            %motorString = sprintf('https://www.maxongroup.us/maxon/view/service_search?query=*&filterKategorie1=Motor&channel=factfinderProducts_ws_maxon_us__en&followSearch=%d&format=JSON', maxonLinkValue);
            %system(sprintf('explorer %s', motorString));
            web('https://www.maxongroup.us/maxon/view/service_search?query=*&filterKategorie1=Motor&channel=factfinderProducts_ws_maxon_us__en&followSearch=10000&format=JSON');
        end

        % Button pushed function: BOMLinktoAdequateBatteriesButton
        function BOMLinktoAdequateBatteriesButtonPushed(app, event)
            if(app.BoMRibbon.SelectedTab == app.BoMBatt1Tab)
                battMAH = app.BOMBatteryCapText1.Value;
                battVolts = 14.8;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt2Tab)
                battMAH = app.BOMBatteryCapText2.Value;
                battVolts = 18.5;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt3Tab)
                battMAH = app.BOMBatteryCapText3.Value;
                battVolts = 22.2;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt4Tab)
                battMAH = app.BOMBatteryCapText4.Value;
                battVolts = 25.9;
            elseif(app.BoMRibbon.SelectedTab == app.BOMBatt5Tab)
                battMAH = app.BOMBatteryCapText5.Value;
                battVolts = 29.6;
            end

            %Test committ for unusual error
            % if value greater than 62000, return 62000
            maxAmpLiPomAh = [430 670 860 1050 1300 1450 1850 2000 2250 2375 2800 3250 ...
                3750 4000 4500 4750 5200 5450 6000 6500 7500 8000 9000 10400 10900 ...
                11000 12000 16000 17000 22000 23000 31000 34000 46000 62000];
            maxAmpsIterate = 1;


            if (battMAH > 62000)
                searchLB = 62000;
            else
                while (battMAH > maxAmpLiPomAh(maxAmpsIterate))
                    maxAmpsIterate = maxAmpsIterate + 1;
                end
                searchLB = maxAmpLiPomAh(maxAmpsIterate);
            end

            if (searchLB == 670)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 1050)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 1300)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 1450)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 3750)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 5200)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            elseif(searchLB == 10400)
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%d-graphene-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            else
                maxAmpsURLVar = 'https://maxamps.com/collections/lipo-%dmah-packs?pf_rs7_voltage=%.2f%%3A%.2f';
            end

            %web(sprintf('https://maxamps.com/collections/lipo-%dmah-packs?pf_rs7_voltage=%.2f%%3A%.2f',searchLB,battVolts,battVolts'));
            web(sprintf(maxAmpsURLVar,searchLB,battVolts,battVolts'));

        end

        % Button pushed function: BOMSaveValuesButton
        function BOMSaveValuesButtonPushed(app, event)
            % Save contents of the deliverables page (in the same folder)
            app.BOMMotorPowerText.Value = app.MotorPower;
            app.BOMMotorMassText.Value = app.editingrobot.motor.mass;
            app.BOMNoLoadText.Value = app.NoloadSpeed;
            app.BOMStallTText.Value = app.StallTorque;

            BOMTextMotorPower = app.BOMMotorPowerText.Value;
            BOMTextMotorMass = app.BOMMotorMassText.Value;
            BOMTextNoLoad = app.BOMNoLoadText.Value;
            BOMTextStallT = app.BOMStallTText.Value;

            BOMTextBatteryCap4 = app.BOMBatteryCapText1.Value;
            BOMTextBatteryWeight4 = (app.BOMBatteryCapText1.Value*(3.7*4))/150;
            BOMTextBatteryVoltage4 = 3.7*4;

            BOMTextBatteryCap5 = app.BOMBatteryCapText2.Value;
            BOMTextBatteryWeight5 = (app.BOMBatteryCapText2.Value*(3.7*5))/150;
            BOMTextBatteryVoltage5 = 3.7*5;

            BOMTextBatteryCap6 = app.BOMBatteryCapText3.Value;
            BOMTextBatteryWeight6 = (app.BOMBatteryCapText3.Value*(3.7*6))/150;
            BOMTextBatteryVoltage6 = 3.7*6;

            BOMTextBatteryCap7 = app.BOMBatteryCapText4.Value;
            BOMTextBatteryWeight7 = (app.BOMBatteryCapText4.Value*(3.7*7))/150;
            BOMTextBatteryVoltage7 = 3.7*7;

            BOMTextBatteryCap8 = app.BOMBatteryCapText5.Value;
            BOMTextBatteryWeight8 = (app.BOMBatteryCapText5.Value*(3.7*8))/150;
            BOMTextBatteryVoltage8 = 3.7*8;

            app.BOMText = sprintf("Motor Power (W): %.2f \n" + ...
                "Total Motor Mass (g): %.2f \n" + ...
                "No Load Speed (RPM): %.2f \n" + ...
                "Stall Torque (Nm): %.2f\n" + ...
                "***************************\n" + ...
                "4 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "5 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "6 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "7 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n" + ...
                "***************************\n" + ...
                "8 Cell Battery:\n" + ...
                "Battery Capactiy (mAh): %.2f \n" + ...
                "Battery Weight (g): %.2f \n" + ...
                "Battery Voltage (V): %.2f \n", BOMTextMotorPower,BOMTextMotorMass,BOMTextNoLoad,BOMTextStallT,BOMTextBatteryCap4,BOMTextBatteryWeight4,...
                BOMTextBatteryVoltage4,BOMTextBatteryCap5,BOMTextBatteryWeight5,BOMTextBatteryVoltage5,BOMTextBatteryCap6,...
                BOMTextBatteryWeight6,BOMTextBatteryVoltage6,BOMTextBatteryCap7,BOMTextBatteryWeight7,BOMTextBatteryVoltage7,...
                BOMTextBatteryCap8,BOMTextBatteryWeight8,BOMTextBatteryVoltage8);
            writematrix(app.BOMFile,'RobotBOM.txt');
            app.OutputPreview.Value = app.BOMText;
        end

        % Menu selected function: OperationManualMenu
        function OperationManualMenuSelected(app, event)
            system("explorer https://web1.eng.famu.fsu.edu/me/senior_design/2023/team505/files/SDT505OperationManual230303.pdf");
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            system("explorer https://www.ciscor.org/");
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            system("explorer https://eng.famu.fsu.edu/");
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

%             % Don't let the user go back to the start screen
%             if (selectedTab == app.StartTab)
%                 app.TabGroup.SelectedTab = event.OldValue;
%             end

            if (selectedTab == app.DeliverablesTab)
                app.BOMMotorPowerText.Value = app.MotorPower;
                app.BOMMotorMassText.Value = app.editingrobot.motor.mass;
                app.BOMNoLoadText.Value = app.NoloadSpeed;
                app.BOMStallTText.Value = app.StallTorque;

                BOMTextMotorPower = app.BOMMotorPowerText.Value;
                BOMTextMotorMass = app.BOMMotorMassText.Value;
                BOMTextNoLoad = app.BOMNoLoadText.Value;
                BOMTextStallT = app.BOMStallTText.Value;

                BOMTextBatteryCap4 = app.BOMBatteryCapText1.Value;
                BOMTextBatteryWeight4 = (app.BOMBatteryCapText1.Value*(3.7*4))/150;
                BOMTextBatteryVoltage4 = 3.7*4;

                BOMTextBatteryCap5 = app.BOMBatteryCapText2.Value;
                BOMTextBatteryWeight5 = (app.BOMBatteryCapText2.Value*(3.7*5))/150;
                BOMTextBatteryVoltage5 = 3.7*5;

                BOMTextBatteryCap6 = app.BOMBatteryCapText3.Value;
                BOMTextBatteryWeight6 = (app.BOMBatteryCapText3.Value*(3.7*6))/150;
                BOMTextBatteryVoltage6 = 3.7*6;

                BOMTextBatteryCap7 = app.BOMBatteryCapText4.Value;
                BOMTextBatteryWeight7 = (app.BOMBatteryCapText4.Value*(3.7*7))/150;
                BOMTextBatteryVoltage7 = 3.7*7;

                BOMTextBatteryCap8 = app.BOMBatteryCapText5.Value;
                BOMTextBatteryWeight8 = (app.BOMBatteryCapText5.Value*(3.7*8))/150;
                BOMTextBatteryVoltage8 = 3.7*8;

                app.BOMText = sprintf("Motor Power (W): %.2f \n" + ...
                    "Total Motor Mass (g): %.2f \n" + ...
                    "No Load Speed (RPM): %.2f \n" + ...
                    "Stall Torque (Nm): %.2f\n" + ...
                    "***************************\n" + ...
                    "4 Cell Battery:\n" + ...
                    "Battery Capactiy (mAh): %.2f \n" + ...
                    "Battery Weight (g): %.2f \n" + ...
                    "Battery Voltage (V): %.2f \n" + ...
                    "***************************\n" + ...
                    "5 Cell Battery:\n" + ...
                    "Battery Capactiy (mAh): %.2f \n" + ...
                    "Battery Weight (g): %.2f \n" + ...
                    "Battery Voltage (V): %.2f \n" + ...
                    "***************************\n" + ...
                    "6 Cell Battery:\n" + ...
                    "Battery Capactiy (mAh): %.2f \n" + ...
                    "Battery Weight (g): %.2f \n" + ...
                    "Battery Voltage (V): %.2f \n" + ...
                    "***************************\n" + ...
                    "7 Cell Battery:\n" + ...
                    "Battery Capactiy (mAh): %.2f \n" + ...
                    "Battery Weight (g): %.2f \n" + ...
                    "Battery Voltage (V): %.2f \n" + ...
                    "***************************\n" + ...
                    "8 Cell Battery:\n" + ...
                    "Battery Capactiy (mAh): %.2f \n" + ...
                    "Battery Weight (g): %.2f \n" + ...
                    "Battery Voltage (V): %.2f \n", BOMTextMotorPower,BOMTextMotorMass,BOMTextNoLoad,BOMTextStallT,BOMTextBatteryCap4,BOMTextBatteryWeight4,...
                    BOMTextBatteryVoltage4,BOMTextBatteryCap5,BOMTextBatteryWeight5,BOMTextBatteryVoltage5,BOMTextBatteryCap6,...
                    BOMTextBatteryWeight6,BOMTextBatteryVoltage6,BOMTextBatteryCap7,BOMTextBatteryWeight7,BOMTextBatteryVoltage7,...
                    BOMTextBatteryCap8,BOMTextBatteryWeight8,BOMTextBatteryVoltage8);
                app.OutputPreview.Value = app.BOMText;
            end
        end

        % Button pushed function: BOMPrevButton
        function BOMPrevButtonPushed(app, event)
            % Move to the Robot Design tab
            app.TabGroup.SelectedTab = app.RobotDesignTab;
        end

        % Button pushed function: DefaultProjectETQuad2023Button
        function DefaultProjectETQuad2023ButtonPushed(app, event)
            % This is literally a copy of the regular load database but
            % just without the stuff we don't need because we already know
            % where this file is.
            % Load the database from the file we already know
            loadingdatabasefile = "ETQuad.mat"; % This should already exist in the folder by default

            load(loadingdatabasefile); % load the database
            app.editingrobot = robot; % Put the database into a temp struct that we work on
            app.editingshadow = robot; % Put the database into a second struct to reset to later
            app.scalingshadow = robot; % Put the database into a third struct to record the scaling state
            % Grab some metadata for later
            app.robotmeta = dir(loadingdatabasefile);


            % Grab the names of all the database fields
            editingdatabasenames = string(fieldnames(app.editingrobot));

            % Build the description
            robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
            robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
            robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
            robotdetailtext4 = sprintf("Date Modified: %s \n\n",app.robotmeta.date);
            robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
            app.TextArea.Value = robotdetail;

            % Read data from the script and put it in a tree
            firstdatabasenode = uitreenode(app.Tree);
            firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry

            % Update the overview picture
            app.Image.ImageSource = app.editingrobot.image;

            % Call the robot plotting function
            robotgrapher(app,app.editingrobot);

            % Swap over to the next tab
            app.StartTabGroup.Visible = "off";
            app.TabGroup.Visible = "on";
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;

            % Update the log
            % Specify that this one is the default one
            logdefaultdataload = sprintf("(%s) Default database '%s' loaded.\n",datetime,loadingdatabasefile);
            app.textlog = append(app.textlog,logdefaultdataload);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end

        % Value changed function: RDBodyHeightcmEditField, 
        % ...and 8 other components
        function RDLegThicknesscmEditFieldValueChanged(app, event)

            fieldtag = event.Source.Tag;
            
            % Reset the view if the user enters a negative value and stop
            if (event.Value < 0)
                app.RDResetButtonPushed(event);
                negativewarning = uifigure;
                negativewarning.Position(3:4) = [500 200];
                beep;
                uialert(negativewarning,"Negative values are not valid for this field. Resetting robot to its initial state.","Warning","CloseFcn",@(h,e) close(negativewarning));
                return
            end

            switch(fieldtag)
                case {"bodylength" "bodyheight" "bodywidth" "leglength" "legwidth" "legthickness"}                    
                    % Inverse math for length
                    inversescale = event.Value/event.PreviousValue;

                    % Put the scaling factor in the scaling field so the
                    % user knows
                    app.RDScalingFactorEditField.Value = inversescale;

                    % Call the scaling function for the new scaling factor
                    app.editingrobot = dynamicScale(app,app.editingrobot,inversescale);

                    % Call the plotting function
                    robotgrapher(app,app.editingrobot);

                    % Build the cumulative scaling amount
                    app.totalscale = app.totalscale * inversescale;
                    app.totalscalecounter = app.totalscalecounter + 1;

                    % Update the log
                    loginversescaling = sprintf("(%s) New scaling factor based on component length set as %0.2g.\n",datetime,inversescale);
                    app.textlog = append(app.textlog,loginversescaling);
                    app.RDTextArea_2.Value = app.textlog;
                    scroll(app.RDTextArea_2,'bottom');

                case {"totalmass" "payloadmass"}
                    % Inverse math for mass
                    inversescale = (event.Value/event.PreviousValue) ^ (1/3);

                    % Put the scaling factor in the scaling field so the
                    % user knows
                    app.RDScalingFactorEditField.Value = inversescale;

                    % Call the scaling function for the new scaling factor
                    app.editingrobot = dynamicScale(app,app.editingrobot,inversescale);

                    % Call the plotting function
                    robotgrapher(app,app.editingrobot);

                    % Build the cumulative scaling amount
                    app.totalscale = app.totalscale * inversescale;
                    app.totalscalecounter = app.totalscalecounter + 1;

                    % Update the log
                    loginversescaling = sprintf("(%s) New scaling factor based on total body mass set as %0.2g.\n",datetime,inversescale);
                    app.textlog = append(app.textlog,loginversescaling);
                    app.RDTextArea_2.Value = app.textlog;
                    scroll(app.RDTextArea_2,'bottom');
                case {"robotruntime"}
                    % Inverse math for mass
                    inversescale = (event.Value/event.PreviousValue) ^ (2);

                    % Put the scaling factor in the scaling field so the
                    % user knows
                    app.RDScalingFactorEditField.Value = inversescale;

                    % Call the scaling function for the new scaling factor
                    app.editingrobot = dynamicScale(app,app.editingrobot,inversescale);

                    % Call the plotting function
                    robotgrapher(app,app.editingrobot);

                    % Because runtime doesn't scale, preserve the value the
                    % user just inputted
                    app.RDRuntimesEditField.Value = event.Value;

                    % Build the cumulative scaling amount
                    app.totalscale = app.totalscale * inversescale;
                    app.totalscalecounter = app.totalscalecounter + 1;

                    % Update the log
                    loginversescaling = sprintf("(%s) New scaling factor based on runtime set as %0.2g.\n",datetime,inversescale);
                    app.textlog = append(app.textlog,loginversescaling);
                    app.RDTextArea_2.Value = app.textlog;
                    scroll(app.RDTextArea_2,'bottom');
            end

            % Enable the reset button if we scaled
            if ~(app.RDScalingFactorEditField.Value == 1)
                app.RDResetButton.Enable = "on";
                app.RDClickNextLabel.Visible = "on";
                app.RDNextButton.Enable = "on";
                % Flash the scaling factor field to let the user know
                app.RDScalingFactorEditField.BackgroundColor = "#00ffff";
                pause(1);
                app.RDScalingFactorEditField.BackgroundColor = "#aaffff";
                pause(0.75);
                app.RDScalingFactorEditField.BackgroundColor = "white";
            end

        end

        % Value changed function: RDBatteryPowerWEditField
        function RDBatteryPowerWEditFieldValueChanged(app, event)
            value = app.RDBatteryPowerWEditField.Value;

        end

        % Callback function: ExportDatabaseButton, ExportDatabaseMenu, 
        % ...and 1 other component
        function ExportDatabaseButtonPushed(app, event)
            % Bunch of stuff to save a file
            exportrobotfilename = sprintf("%s.mat",app.editingrobot.name);
            robot = app.editingrobot;
            [filename, filepath] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Database', exportrobotfilename);
            if ischar(filename)
                save(fullfile(filepath, filename), 'robot');
            end

            % Stop everything if user hits cancel
            if (filename == 0)
                % Bring the window back into focus
                app.RoboforgeDemoUIFigure.Visible = 'off';
                app.RoboforgeDemoUIFigure.Visible = 'on';
                return
            end

            % Bring the window back into focus
            app.RoboforgeDemoUIFigure.Visible = 'off';
            app.RoboforgeDemoUIFigure.Visible = 'on';
            exportfilename = append(filepath,filename);

            % Update the log
            logexportdatabase = sprintf("(%s) Database saved to '%s'.\n",datetime,exportfilename);
            app.textlog = append(app.textlog,logexportdatabase);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            % Make savedata struct to hold current state of app
            savedata.editingrobot = app.editingrobot;
            savedata.textlog = app.textlog;
            savedata.editingshadow = app.editingshadow;
            savedata.totalscale = app.totalscale;
            savedata.totalscalecounter = app.totalscalecounter;
            savedata.robotmeta = app.robotmeta;

            % Stuff to save a file
            savedatafilename = "project.mat";
            [filename, filepath] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Project File', savedatafilename);
            if ischar(filename)
                save(fullfile(filepath, filename), 'savedata');
            end
            % Bring the window back into focus
            app.RoboforgeDemoUIFigure.Visible = 'off';
            app.RoboforgeDemoUIFigure.Visible = 'on';
            savedatafilename = append(filepath,filename);

            % Update the log
            logsaveproject = sprintf("(%s) Project file saved to '%s'.\n",datetime,savedatafilename);
            app.textlog = append(app.textlog,logsaveproject);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end

        % Callback function: LoadProjectButton, LoadProjectMenu, 
        % ...and 1 other component
        function LoadProjectButtonPushed(app, event)
            % Load the project from file
            [loadingprojectfile, loadingprojectpath] = uigetfile(".mat"); % This gets the filename and path
            % Stop everything if user hits cancel
            if (loadingprojectfile == 0)
                % Bring the window back into focus
                app.RoboforgeDemoUIFigure.Visible = 'off';
                app.RoboforgeDemoUIFigure.Visible = 'on';
                return
            end
            % Bring the window back into focus
            app.RoboforgeDemoUIFigure.Visible = 'off';
            app.RoboforgeDemoUIFigure.Visible = 'on';
            app.StartTabGroup.Visible = "off";
            app.TabGroup.Visible = "on";
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;

            % Load the project file
            lastloadedprojectfile = append(loadingprojectpath,loadingprojectfile);
            load(lastloadedprojectfile);

            % Load everything into place
            app.editingrobot = savedata.editingrobot;
            app.editingshadow = savedata.editingshadow;
            app.textlog = savedata.textlog;
            app.totalscale = savedata.totalscale;
            app.totalscalecounter = savedata.totalscalecounter;
            app.robotmeta = savedata.robotmeta;

            % Space here to implement stuff for recent project files later
            %%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%

            % Database browser building stuff
            % Grab the names of all the database fields
            editingdatabasenames = string(fieldnames(app.editingrobot));

            % Build the description
            robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
            robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
            robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
            robotdetailtext4 = sprintf("Date Modified: %s \n\n",app.robotmeta.date);
            robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
            app.TextArea.Value = robotdetail;

            % Read data from the script and put it in a tree
            firstdatabasenode = uitreenode(app.Tree);
            firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry

            % Update the overview picture
            app.Image.ImageSource = app.editingrobot.image;

            % Call the robot plotting function
            robotgrapher(app,app.editingrobot);

            % Update the log
            logloadproject = sprintf("(%s) Project loaded from '%s'. Loaded database %s from previous project.\n",datetime,lastloadedprojectfile,app.editingrobot.name);
            app.textlog = append(app.textlog,logloadproject);
            app.RDTextArea_2.Value = app.textlog;
            scroll(app.RDTextArea_2,'bottom');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RoboforgeDemoUIFigure = uifigure('Visible', 'off');
            app.RoboforgeDemoUIFigure.Name = 'Roboforge Demo';
            app.RoboforgeDemoUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoboforgeDemoUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.RoboforgeDemoUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.RecentProjectsMenu = uimenu(app.FileMenu);
            app.RecentProjectsMenu.Text = 'Recent Projects..';
            app.CISCORDUOMenu = uimenu(app.RecentProjectsMenu);
            app.CISCORDUOMenu.Text = 'CISCOR DUO';
            app.MEExcavatorMenu = uimenu(app.RecentProjectsMenu);
            app.MEExcavatorMenu.Text = 'ME Excavator';
            app.LoadProjectMenu = uimenu(app.FileMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.NewDatabaseMenu = uimenu(app.FileMenu);
            app.NewDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @NewDatabaseButtonPushed, true);
            app.NewDatabaseMenu.Separator = 'on';
            app.NewDatabaseMenu.Text = 'New Database';
            app.LoadDatabaseMenu = uimenu(app.FileMenu);
            app.LoadDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDatabaseMenuSelected, true);
            app.LoadDatabaseMenu.Text = 'Load Database';
            app.RecentDatabasesMenu = uimenu(app.FileMenu);
            app.RecentDatabasesMenu.Text = 'Recent Databases';
            app.ExportDatabaseMenu = uimenu(app.FileMenu);
            app.ExportDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatabaseButtonPushed, true);
            app.ExportDatabaseMenu.Text = 'Export Database';
            app.HelpMenu = uimenu(app.RoboforgeDemoUIFigure);
            app.HelpMenu.Text = 'Help';
            app.OperationManualMenu = uimenu(app.HelpMenu);
            app.OperationManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OperationManualMenuSelected, true);
            app.OperationManualMenu.Text = 'Operation Manual';
            app.AboutThisToolMenu = uimenu(app.HelpMenu);
            app.AboutThisToolMenu.Text = 'About This Tool';
            app.TabGroup = uitabgroup(app.RoboforgeDemoUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DatabaseBrowserTab = uitab(app.TabGroup);
            app.DatabaseBrowserTab.Title = 'Database Browser';
            app.AvailableDatabasesPanel = uipanel(app.DatabaseBrowserTab);
            app.AvailableDatabasesPanel.Title = 'Available Databases';
            app.NewDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.NewDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @NewDatabaseButtonPushed, true);
            app.NewDatabaseButton.Text = 'New Database';
            app.LoadDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseMenuSelected, true);
            app.LoadDatabaseButton.Text = 'Load Database';
            app.ExportDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.ExportDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatabaseButtonPushed, true);
            app.ExportDatabaseButton.Text = 'Export Database';
            app.Tree = uitree(app.AvailableDatabasesPanel, 'checkbox');
            app.SaveSelectionButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.SaveSelectionButton.Text = 'Save Selection';
            app.NextButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.DetailsPanel = uipanel(app.DatabaseBrowserTab);
            app.DetailsPanel.Title = 'Details';
            app.TextArea = uitextarea(app.DetailsPanel);
            app.TextArea.Editable = 'off';
            app.RestoreDefaultsButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.OverviewPanel = uipanel(app.DatabaseBrowserTab);
            app.OverviewPanel.Title = 'Overview';
            app.Image = uiimage(app.OverviewPanel);
            app.CreateviewandloadrobotdatabasesLabel = uilabel(app.DatabaseBrowserTab);
            app.CreateviewandloadrobotdatabasesLabel.Text = 'Create, view, and load robot databases.';
            app.RobotDesignTab = uitab(app.TabGroup);
            app.RobotDesignTab.Title = 'Robot Design';
            app.SketchViewPanel = uipanel(app.RobotDesignTab);
            app.SketchViewPanel.Title = 'Sketch View';
            app.RDUIAxes = uiaxes(app.SketchViewPanel);
            xlabel(app.RDUIAxes, 'X')
            ylabel(app.RDUIAxes, 'Y')
            zlabel(app.RDUIAxes, 'Z')
            app.TotalBodyMassLabel = uilabel(app.SketchViewPanel);
            app.TotalBodyMassLabel.Text = 'Total Body Mass (kg)';
            app.RDTotalBodyMasskgEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDTotalBodyMasskgEditField.ValueDisplayFormat = '%6.6g';
            app.RDTotalBodyMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDTotalBodyMasskgEditField.Tag = 'totalmass';
            app.RDTotalBodyMasskgEditField.Tooltip = {'The total mass of the robot, including motors, chassis, legs, etc. This does not include any additional payload.'};
            app.BodyLengthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyLengthcmEditFieldLabel.Text = 'Body Length (cm)';
            app.RDBodyLengthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDBodyLengthcmEditField.ValueDisplayFormat = '%6.6g';
            app.RDBodyLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDBodyLengthcmEditField.Tag = 'bodylength';
            app.RDBodyLengthcmEditField.Tooltip = {'The length of the chassis of the robot from the front to the back.'};
            app.BodyWidthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyWidthcmEditFieldLabel.Text = 'Body Width (cm)';
            app.RDBodyWidthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDBodyWidthcmEditField.ValueDisplayFormat = '%6.6g';
            app.RDBodyWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDBodyWidthcmEditField.Tag = 'bodywidth';
            app.RDBodyWidthcmEditField.Tooltip = {'The width of the chassis of the robot from the left to the right, or vice versa.'};
            app.BodyHeightcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyHeightcmEditFieldLabel.Text = 'Body Height (cm)';
            app.RDBodyHeightcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDBodyHeightcmEditField.ValueDisplayFormat = '%6.6g';
            app.RDBodyHeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDBodyHeightcmEditField.Tag = 'bodyheight';
            app.RDBodyHeightcmEditField.Tooltip = {'The height of the chassis of the robot from the bottom to the top, or vice versa. This is not the height measured from the floor.'};
            app.LegLengthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegLengthcmEditFieldLabel.Text = 'Leg Length (cm)';
            app.RDLegLengthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDLegLengthcmEditField.ValueDisplayFormat = '%6.6g';
            app.RDLegLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDLegLengthcmEditField.Tag = 'leglength';
            app.RDLegLengthcmEditField.Tooltip = {'The virtual length of the robot''s leg. For example, this could be the pin to pin distance for a 5-bar or the pin to ground contact distance in a RR leg.'};
            app.LegWidthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegWidthcmEditFieldLabel.Text = 'Leg Width (cm)';
            app.RDLegWidthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDLegWidthcmEditField.ValueDisplayFormat = '%6.6g';
            app.RDLegWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDLegWidthcmEditField.Tag = 'legwidth';
            app.RDLegWidthcmEditField.Tooltip = {'The width of the leg as measured in the direction of the front of the robot.'};
            app.LegThicknesscmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegThicknesscmEditFieldLabel.Text = 'Leg Thickness (cm)';
            app.RDLegThicknesscmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDLegThicknesscmEditField.ValueDisplayFormat = '%6.6g';
            app.RDLegThicknesscmEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDLegThicknesscmEditField.Tag = 'legthickness';
            app.RDLegThicknesscmEditField.Tooltip = {'The thickness of the leg, measured toward the robot body.'};
            app.MotorPowerWLabel = uilabel(app.SketchViewPanel);
            app.MotorPowerWLabel.Text = 'Motor Power (W)';
            app.RDBatteryPowerWEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDBatteryPowerWEditField.ValueDisplayFormat = '%6.6g';
            app.RDBatteryPowerWEditField.ValueChangedFcn = createCallbackFcn(app, @RDBatteryPowerWEditFieldValueChanged, true);
            app.RDBatteryPowerWEditField.Editable = 'off';
            app.RDBatteryPowerWEditField.Tooltip = {'The battery power required to operate the motors for the desired run time.'};
            app.StallTorqueNmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.StallTorqueNmEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.RDStallTorqueNmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDStallTorqueNmEditField.ValueDisplayFormat = '%6.6g';
            app.RDStallTorqueNmEditField.Editable = 'off';
            app.RDStallTorqueNmEditField.Tooltip = {'The required stall torque speed of the motor and gearbox combination. This assumes one motor at the hip.'};
            app.NoloadSpeedRPMEditFieldLabel = uilabel(app.SketchViewPanel);
            app.NoloadSpeedRPMEditFieldLabel.Text = 'No-load Speed (RPM)';
            app.RDNoloadSpeedRPMEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDNoloadSpeedRPMEditField.ValueDisplayFormat = '%6.6g';
            app.RDNoloadSpeedRPMEditField.Editable = 'off';
            app.RDNoloadSpeedRPMEditField.Tooltip = {'The required no-load speed of the motor and gearbox combination. This assumes one motor at the hip.'};
            app.RuntimesLabel = uilabel(app.SketchViewPanel);
            app.RuntimesLabel.Text = 'Runtime (s)';
            app.RDRuntimesEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDRuntimesEditField.ValueDisplayFormat = '%6.6g';
            app.RDRuntimesEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDRuntimesEditField.Tag = 'robotruntime';
            app.RDRuntimesEditField.Tooltip = {'The required no-load speed of the motor and gearbox combination. This assumes one motor at the hip.'};
            app.TotalBodyMassLabel_2 = uilabel(app.SketchViewPanel);
            app.TotalBodyMassLabel_2.Text = 'Payload Mass (kg)';
            app.RDPayloadMasskgEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.RDPayloadMasskgEditField.ValueDisplayFormat = '%6.6g';
            app.RDPayloadMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @RDLegThicknesscmEditFieldValueChanged, true);
            app.RDPayloadMasskgEditField.Tag = 'payloadmass';
            app.RDPayloadMasskgEditField.Tooltip = {'The total mass of the robot, including motors, chassis, legs, etc. This does not include any additional payload.'};
            app.RDHoverLabel = uilabel(app.SketchViewPanel);
            app.RDHoverLabel.Text = 'Hover over an entry box to view more information.';
            app.RDLogPanel = uipanel(app.RobotDesignTab);
            app.RDLogPanel.Title = 'Log';
            app.RDTextArea_2 = uitextarea(app.RDLogPanel);
            app.RDTextArea_2.Editable = 'off';
            app.RDExportLogButton = uibutton(app.RDLogPanel, 'push');
            app.RDExportLogButton.ButtonPushedFcn = createCallbackFcn(app, @RDExportLogButtonPushed, true);
            app.RDExportLogButton.Text = 'Export Log';
            app.RDSaveSelectionButton = uibutton(app.RobotDesignTab, 'push');
            app.RDSaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatabaseButtonPushed, true);
            app.RDSaveSelectionButton.Tooltip = {'Save in-progress database to file based on current scaling factors.'};
            app.RDSaveSelectionButton.Text = 'Export Database';
            app.RDNextButton = uibutton(app.RobotDesignTab, 'push');
            app.RDNextButton.ButtonPushedFcn = createCallbackFcn(app, @RDNextButtonPushed, true);
            app.RDNextButton.Enable = 'off';
            app.RDNextButton.Text = 'Next';
            app.RDPreviousButton = uibutton(app.RobotDesignTab, 'push');
            app.RDPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @RDPreviousButtonPushed, true);
            app.RDPreviousButton.Text = 'Previous';
            app.ScalingRulesPanel = uipanel(app.RobotDesignTab);
            app.ScalingRulesPanel.Title = 'Scaling Rules';
            app.ScalingFactorLabel = uilabel(app.ScalingRulesPanel);
            app.ScalingFactorLabel.Text = 'Scaling Factor';
            app.RDScalingFactorEditField = uieditfield(app.ScalingRulesPanel, 'numeric');
            app.RDScalingFactorEditField.ValueDisplayFormat = '%6.2g';
            app.RDScalingFactorEditField.Tooltip = {'This takes an existing robot and scales it using dynamic similarlity. The scaling factor is a length scaling factor.'};
            app.RDScalingFactorEditField.Value = 1;
            app.RDSubmitButton = uibutton(app.ScalingRulesPanel, 'push');
            app.RDSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @RDSubmitButtonPushed, true);
            app.RDSubmitButton.Text = 'Submit';
            app.RDResetButton = uibutton(app.ScalingRulesPanel, 'push');
            app.RDResetButton.ButtonPushedFcn = createCallbackFcn(app, @RDResetButtonPushed, true);
            app.RDResetButton.Text = 'Reset';
            app.ModelingPanel = uipanel(app.RobotDesignTab);
            app.ModelingPanel.Title = 'Modeling';
            app.RDSubmitButton_2 = uibutton(app.ModelingPanel, 'push');
            app.RDSubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitandRunButtonPushed, true);
            app.RDSubmitButton_2.Text = 'Submit';
            app.RDRunModelingFunctionsLabel = uilabel(app.ModelingPanel);
            app.RDRunModelingFunctionsLabel.Text = 'Run Modeling Functions';
            app.RDMassBudgetPanel = uipanel(app.RobotDesignTab);
            app.RDMassBudgetPanel.Title = 'Mass Budget';
            app.MotorsEditFieldLabel = uilabel(app.RDMassBudgetPanel);
            app.MotorsEditFieldLabel.Text = 'Motors';
            app.RDMotorsEditField = uieditfield(app.RDMassBudgetPanel, 'numeric');
            app.RDMotorsEditField.ValueDisplayFormat = '%.1f';
            app.RDMotorsEditField.Editable = 'off';
            app.LegsEditFieldLabel = uilabel(app.RDMassBudgetPanel);
            app.LegsEditFieldLabel.Text = 'Legs';
            app.RDLegsEditField = uieditfield(app.RDMassBudgetPanel, 'numeric');
            app.RDLegsEditField.ValueDisplayFormat = '%.1f';
            app.RDLegsEditField.Editable = 'off';
            app.BatteriesEditFieldLabel = uilabel(app.RDMassBudgetPanel);
            app.BatteriesEditFieldLabel.Text = 'Batteries';
            app.RDBatteriesEditField = uieditfield(app.RDMassBudgetPanel, 'numeric');
            app.RDBatteriesEditField.ValueDisplayFormat = '%.1f';
            app.RDBatteriesEditField.Editable = 'off';
            app.ChassisEditField_2Label = uilabel(app.RDMassBudgetPanel);
            app.ChassisEditField_2Label.Text = 'Chassis';
            app.RDChassisEditField = uieditfield(app.RDMassBudgetPanel, 'numeric');
            app.RDChassisEditField.ValueDisplayFormat = '%.1f';
            app.RDChassisEditField.Editable = 'off';
            app.RDPercent_1 = uilabel(app.RDMassBudgetPanel);
            app.RDPercent_1.Text = '%';
            app.RDPercent_2 = uilabel(app.RDMassBudgetPanel);
            app.RDPercent_2.Text = '%';
            app.RDPercent_3 = uilabel(app.RDMassBudgetPanel);
            app.RDPercent_3.Text = '%';
            app.RDPercent_4 = uilabel(app.RDMassBudgetPanel);
            app.RDPercent_4.Text = '%';
            app.RDDescriptorTextLabel = uilabel(app.RobotDesignTab);
            app.RDDescriptorTextLabel.Text = 'View your imported robot, assign paramenters, and scale your robot as needed. Run modeling functions to receive mass and power requirements for your current scale.';
            app.RDClickNextLabel = uilabel(app.RobotDesignTab);
            app.RDClickNextLabel.Visible = 'off';
            app.RDClickNextLabel.Text = 'Click "Next" to view Deliverables and your Bill of Materials.';
            app.DeliverablesTab = uitab(app.TabGroup);
            app.DeliverablesTab.Title = 'Deliverables';
            app.BOMPrevButton = uibutton(app.DeliverablesTab, 'push');
            app.BOMPrevButton.ButtonPushedFcn = createCallbackFcn(app, @BOMPrevButtonPushed, true);
            app.BOMPrevButton.Text = 'Previous';
            app.BillofMaterialsLabel = uilabel(app.DeliverablesTab);
            app.BillofMaterialsLabel.Text = 'Bill of Materials';
            app.MotorPanel = uipanel(app.DeliverablesTab);
            app.MotorPanel.Title = 'Motor Panel';
            app.BOMMotorPowerLabel = uilabel(app.MotorPanel);
            app.BOMMotorPowerLabel.Text = 'Motor Power (W)';
            app.BOMMotorPowerText = uieditfield(app.MotorPanel, 'numeric');
            app.BOMMotorMassLabel = uilabel(app.MotorPanel);
            app.BOMMotorMassLabel.Text = 'Total Motor Mass (kg)';
            app.BOMMotorMassText = uieditfield(app.MotorPanel, 'numeric');
            app.BOMLinktoAdequateMotorsButton = uibutton(app.MotorPanel, 'push');
            app.BOMLinktoAdequateMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @BOMLinktoAdequateMotorsButtonPushed, true);
            app.BOMLinktoAdequateMotorsButton.Text = {'Link to Adequate Motors'; ''};
            app.BOMCopyButton_1 = uiimage(app.MotorPanel);
            app.BOMCopyButton_1.ImageClickedFcn = createCallbackFcn(app, @BOMCopyButton_1ImageClicked, true);
            app.BOMCopyButton_1.ImageSource = 'copyLogo.png';
            app.BOMNoLoadLabel = uilabel(app.MotorPanel);
            app.BOMNoLoadLabel.Text = 'No Load Speed (RPM)';
            app.BOMNoLoadText = uieditfield(app.MotorPanel, 'numeric');
            app.BOMStallTLabel = uilabel(app.MotorPanel);
            app.BOMStallTLabel.Text = 'Stall Torque (Nm)';
            app.BOMStallTText = uieditfield(app.MotorPanel, 'numeric');
            app.BatteryPanel = uipanel(app.DeliverablesTab);
            app.BatteryPanel.Title = 'Battery Panel';
            app.BoMRibbon = uitabgroup(app.BatteryPanel);
            app.BoMBatt1Tab = uitab(app.BoMRibbon);
            app.BoMBatt1Tab.Title = '4 Cell';
            app.BOMBatteryCapLabel1 = uilabel(app.BoMBatt1Tab);
            app.BOMBatteryCapLabel1.Text = 'Battery Capacity (mAh)';
            app.BOMBatteryCapText1 = uieditfield(app.BoMBatt1Tab, 'numeric');
            app.BOMBatteryWeightLabel1 = uilabel(app.BoMBatt1Tab);
            app.BOMBatteryWeightLabel1.Text = 'Battery Weight (g)';
            app.BOMBatteryWeightText1 = uieditfield(app.BoMBatt1Tab, 'numeric');
            app.BOMBatteryVoltageLabel1 = uilabel(app.BoMBatt1Tab);
            app.BOMBatteryVoltageLabel1.Text = 'Battery Voltage (V)';
            app.BOMBatteryVoltageText1 = uieditfield(app.BoMBatt1Tab, 'numeric');
            app.BOMBatteryVoltageText1.Value = 14.8;
            app.BOMBatt2Tab = uitab(app.BoMRibbon);
            app.BOMBatt2Tab.Title = '5 Cell';
            app.BOMBatteryCapLabel2 = uilabel(app.BOMBatt2Tab);
            app.BOMBatteryCapLabel2.Text = 'Battery Capacity (mAh)';
            app.BOMBatteryCapText2 = uieditfield(app.BOMBatt2Tab, 'numeric');
            app.BOMBatteryWeightLabel2 = uilabel(app.BOMBatt2Tab);
            app.BOMBatteryWeightLabel2.Text = 'Battery Weight (g)';
            app.BOMBatteryWeightText2 = uieditfield(app.BOMBatt2Tab, 'numeric');
            app.BOMBatteryVoltageLabel2 = uilabel(app.BOMBatt2Tab);
            app.BOMBatteryVoltageLabel2.Text = 'Battery Voltage (V)';
            app.BOMBatteryVoltageText2 = uieditfield(app.BOMBatt2Tab, 'numeric');
            app.BOMBatteryVoltageText2.Value = 18.5;
            app.BOMBatt3Tab = uitab(app.BoMRibbon);
            app.BOMBatt3Tab.Title = '6 Cell';
            app.BOMBatteryCapLabel3 = uilabel(app.BOMBatt3Tab);
            app.BOMBatteryCapLabel3.Text = 'Battery Capacity (mAh)';
            app.BOMBatteryCapText3 = uieditfield(app.BOMBatt3Tab, 'numeric');
            app.BOMBatteryWeightLabel3 = uilabel(app.BOMBatt3Tab);
            app.BOMBatteryWeightLabel3.Text = 'Battery Weight (g)';
            app.BOMBatteryWeightText3 = uieditfield(app.BOMBatt3Tab, 'numeric');
            app.BOMBatteryVoltageLabel3 = uilabel(app.BOMBatt3Tab);
            app.BOMBatteryVoltageLabel3.Text = 'Battery Voltage (V)';
            app.BOMBatteryVoltageText3 = uieditfield(app.BOMBatt3Tab, 'numeric');
            app.BOMBatteryVoltageText3.Value = 22.2;
            app.BOMBatt4Tab = uitab(app.BoMRibbon);
            app.BOMBatt4Tab.Title = '7 Cell';
            app.BOMBatteryCapLabel4 = uilabel(app.BOMBatt4Tab);
            app.BOMBatteryCapLabel4.Text = 'Battery Capacity (mAh)';
            app.BOMBatteryCapText4 = uieditfield(app.BOMBatt4Tab, 'numeric');
            app.BOMBatteryWeightLabel4 = uilabel(app.BOMBatt4Tab);
            app.BOMBatteryWeightLabel4.Text = 'Battery Weight (g)';
            app.BOMBatteryWeightText4 = uieditfield(app.BOMBatt4Tab, 'numeric');
            app.BOMBatteryVoltageLabel4 = uilabel(app.BOMBatt4Tab);
            app.BOMBatteryVoltageLabel4.Text = 'Battery Voltage (V)';
            app.BOMBatteryVoltageText4 = uieditfield(app.BOMBatt4Tab, 'numeric');
            app.BOMBatteryVoltageText4.Value = 25.9;
            app.BOMBatt5Tab = uitab(app.BoMRibbon);
            app.BOMBatt5Tab.Title = '8 Cell';
            app.BOMBatteryCapLabel5 = uilabel(app.BOMBatt5Tab);
            app.BOMBatteryCapLabel5.Text = 'Battery Capacity (mAh)';
            app.BOMBatteryCapText5 = uieditfield(app.BOMBatt5Tab, 'numeric');
            app.BOMBatteryWeightLabel5 = uilabel(app.BOMBatt5Tab);
            app.BOMBatteryWeightLabel5.Text = 'Battery Weight (g)';
            app.BOMBatteryWeightText5 = uieditfield(app.BOMBatt5Tab, 'numeric');
            app.BOMBatteryVoltageLabel5 = uilabel(app.BOMBatt5Tab);
            app.BOMBatteryVoltageLabel5.Text = 'Battery Voltage (V)';
            app.BOMBatteryVoltageText5 = uieditfield(app.BOMBatt5Tab, 'numeric');
            app.BOMBatteryVoltageText5.Value = 29.6;
            app.BOMLinktoAdequateBatteriesButton = uibutton(app.BatteryPanel, 'push');
            app.BOMLinktoAdequateBatteriesButton.ButtonPushedFcn = createCallbackFcn(app, @BOMLinktoAdequateBatteriesButtonPushed, true);
            app.BOMLinktoAdequateBatteriesButton.Text = {'Link to Adequate Batteries'; ''};
            app.BOMCopyButton_2 = uiimage(app.BatteryPanel);
            app.BOMCopyButton_2.ImageClickedFcn = createCallbackFcn(app, @BOMCopyButton_2ImageClicked, true);
            app.BOMCopyButton_2.ImageSource = 'copyLogo.png';
            app.BOMSaveValuesButton = uibutton(app.DeliverablesTab, 'push');
            app.BOMSaveValuesButton.ButtonPushedFcn = createCallbackFcn(app, @BOMSaveValuesButtonPushed, true);
            app.BOMSaveValuesButton.Text = 'Save Values';
            app.OutputPreviewPanel = uipanel(app.DeliverablesTab);
            app.OutputPreviewPanel.Title = 'Output Preview';
            app.OutputPreview = uitextarea(app.OutputPreviewPanel);
            app.StartTabGroup = uitabgroup(app.RoboforgeDemoUIFigure);
            app.StartTab = uitab(app.StartTabGroup);
            app.StartTab.Title = 'Start';
            app.Image2 = uiimage(app.StartTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ciscorlab.jpg');
            app.WelcometotheRoboForgeDemoLabel = uilabel(app.StartTab);
            app.WelcometotheRoboForgeDemoLabel.Text = 'Welcome to the RoboForge Demo';
            app.NewProjectButton = uibutton(app.StartTab, 'push');
            app.NewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProjectButton.Text = 'New Project';
            app.LoadProjectButton = uibutton(app.StartTab, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.DefaultProjectETQuad2023Button = uibutton(app.StartTab, 'push');
            app.DefaultProjectETQuad2023Button.ButtonPushedFcn = createCallbackFcn(app, @DefaultProjectETQuad2023ButtonPushed, true);
            app.DefaultProjectETQuad2023Button.Text = {'Default Project'; '(ETQuad 2023)'};
            app.Image3 = uiimage(app.StartTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'ciscorlogo.png');
            app.Image4 = uiimage(app.StartTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'famufsulogo.png');
            app.Version07Label = uilabel(app.StartTab);
            app.Version07Label.Text = 'Version 0.7';
            % Show the figure after all components are created
            app.RoboforgeDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mockuppart3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboforgeDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboforgeDemoUIFigure)
        end
    end
end